classdef Encryption < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure   matlab.ui.Figure
        Panel      matlab.ui.container.Panel
        UIAxes     matlab.ui.control.UIAxes
        Panel_2    matlab.ui.container.Panel
        UIAxes_2   matlab.ui.control.UIAxes
        Label      matlab.ui.control.Label
        Button     matlab.ui.control.Button
        Label_2    matlab.ui.control.Label
        Button_3   matlab.ui.control.Button
        Label_3    matlab.ui.control.Label
        Spinner    matlab.ui.control.Spinner
        Label_4    matlab.ui.control.Label
        EditField  matlab.ui.control.EditField
        Label_5    matlab.ui.control.Label
        Button_4   matlab.ui.control.Button
    end

    
    properties (Access = public)
        imSrc; % Description
        imOut % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            app.EditField.Value="您正在选择要传入的图像~";
            pause(0.01);
            [filename,pathname]=uigetfile(["*.jpg";"*.jpeg";"*.png";"*.tif";"*.tiff";"*.bmp"],"选择要加密的图像");
            if isequal(filename,0) || isequal(pathname,0)
                errordlg("您没有选择任何图像","出错了！");
                app.EditField.Value="您没有选择任何图像~";
            else
                app.EditField.Value="图像正在传入，请稍等...";
                pause(0.01);
                filePath=strcat(pathname,filename);
                app.imSrc=imread(filePath);
                imshow(app.imSrc,"Parent",app.UIAxes);
                app.EditField.Value="待加密的图像已传入完成~";
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.EditField.Value="图像加密中，请稍等...";
            pause(0.01);
            count=app.Spinner.Value;
            app.imOut=Digital_Image_Encryption(app.imSrc,count);
            imshow(app.imOut,"Parent",app.UIAxes_2);
            app.EditField.Value="图像已加密完毕~";
        end

        % Value changed function: Spinner
        function SpinnerValueChanged(app, event)
            %value = app.Spinner.Value;
            
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            app.EditField.Value="您正在选择保存的路径~";
            pause(0.01);
            [filename,pathname]=uiputfile(["*.png";"*.tif";"*.tiff";"*.bmp"],"选择保存路径");
            if isequal(filename,0) || isequal(pathname,0)
                errordlg("您没有选择任何图像","出错了！");
                app.EditField.Value="您没有选择任何图像~";
            else
                app.EditField.Value="图像正在保存，请稍等...";
                pause(0.01);
                filePath=strcat(pathname,filename);
                imwrite(app.imOut,filePath);
                pause(1.5);
                app.EditField.Value="图像已保存成功~";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '数字图像加密系统';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '待加密图像';
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.Visible = 'off';
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_2.Title = '加密后图像';
            app.UIAxes_2 = uiaxes(app.Panel_2);
            title(app.UIAxes_2, '')
            xlabel(app.UIAxes_2, '')
            ylabel(app.UIAxes_2, '')
            app.UIAxes_2.Visible = 'off';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '    数字图像加密系统';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '加密';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '→';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '选择图像';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '加  密';
            app.Spinner = uispinner(app.UIFigure);
            app.Spinner.Limits = [1 Inf];
            app.Spinner.ValueDisplayFormat = '%.0f';
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.Spinner.Value = 1;
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '次  数';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Editable = 'off';
            app.EditField.Value = '您没有进行任何操作~';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '运行状况';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '保 存';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Encryption

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end